classdef volume_display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FixedYaxisSwitch              matlab.ui.control.ToggleSwitch
        FixedYaxisSwitchLabel         matlab.ui.control.Label
        v031Label                     matlab.ui.control.Label
        DoublePeakTimeField           matlab.ui.control.NumericEditField
        AveragedoublepeakfittingtimemsplaneEditFieldLabel  matlab.ui.control.Label
        SinglePeakTimeField           matlab.ui.control.NumericEditField
        AveragesinglepeakfittingtimemsplaneEditFieldLabel  matlab.ui.control.Label
        TotTimeField                  matlab.ui.control.NumericEditField
        TotalprocessingtimesLabel     matlab.ui.control.Label
        DoublePeakFittingloadedLamp   matlab.ui.control.Lamp
        DoublePeakFittingloadedLampLabel  matlab.ui.control.Label
        LinkZplanesSwitch             matlab.ui.control.ToggleSwitch
        LinkZplanesSwitchLabel        matlab.ui.control.Label
        Imagedplane2Panel             matlab.ui.container.Panel
        ClickonplotButton_2           matlab.ui.control.StateButton
        MaxValEditField_2             matlab.ui.control.NumericEditField
        MaxvalueEditFieldLabel_2      matlab.ui.control.Label
        MinValEditField_2             matlab.ui.control.NumericEditField
        MinvalueEditFieldLabel_2      matlab.ui.control.Label
        ParametertodisplayDropDown_2  matlab.ui.control.DropDown
        ParametertodisplayDropDown_2Label  matlab.ui.control.Label
        ZplaneSpinner_2               matlab.ui.control.Spinner
        ZplaneSpinner_2Label          matlab.ui.control.Label
        Histogram_2                   matlab.ui.control.UIAxes
        CurrentPlane_2                matlab.ui.control.UIAxes
        ImagedplanePanel              matlab.ui.container.Panel
        ClickonplotButton             matlab.ui.control.StateButton
        MaxValEditField               matlab.ui.control.NumericEditField
        MaxvalueEditFieldLabel        matlab.ui.control.Label
        MinValEditField               matlab.ui.control.NumericEditField
        MinvalueEditFieldLabel        matlab.ui.control.Label
        ParametertodisplayDropDown    matlab.ui.control.DropDown
        ParametertodisplayLabel       matlab.ui.control.Label
        ZplaneSpinner                 matlab.ui.control.Spinner
        ZplaneSpinnerLabel            matlab.ui.control.Label
        Histogram                     matlab.ui.control.UIAxes
        CurrentPlane                  matlab.ui.control.UIAxes
        FittingData                   matlab.ui.control.Table
        PixelRawData                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        volume % All information about single peak fitting
        volume_double_peak % All information about double peak fitting
        use_double_peak %
        s_1 % click on plot 1
        s_2 % click on plot 2
        x_abs; %pixel on focus
        y_abs; %pixel on focus
        x_pixel_to_um ;
        y_pixel_to_um ;
        
        experiment_settings;        
        AIC;
        use_AIC; 
        abs_Y_range; % Description
    end
    
    methods (Access = private)
        
        function results = update_display(app, panel)
            switch(panel)
                case 1
                    app.s_1 = display_plane(app, ...
                        app.ParametertodisplayDropDown.Value, ...
                        app.ZplaneSpinner.Value, ...
                        app.CurrentPlane, ...
                        app.MinValEditField.Value, ...
                        app.MaxValEditField.Value, ...
                        app.ClickonplotButton.Value, ...
                        app.Histogram);
                    app.CurrentPlane.Title.String = "Imaged Plane - " + app.ParametertodisplayDropDown.Value;
                case 2
                    app.s_2 = display_plane(app, ...
                        app.ParametertodisplayDropDown_2.Value, ...
                        app.ZplaneSpinner_2.Value, ...
                        app.CurrentPlane_2, ...
                        app.MinValEditField_2.Value, ...
                        app.MaxValEditField_2.Value, ...
                        app.ClickonplotButton_2.Value, ...
                        app.Histogram_2);
                    app.CurrentPlane_2.Title.String = "Imaged Plane - " + app.ParametertodisplayDropDown_2.Value;
            end
            
        end
        
        
        % ParameterToDisplay = app.ParametertodisplayDropDown.Value
        % ZPlaneToDisplay = app.ZplaneSpinner.Value
        % DisplayingAxis = app.CurrentPlane
        % caxisMinVal = app.MinValEditField.Value
        % caxisMaxVal = app.MaxValEditField.Value
        % ClickOnPlot = app.ClickonplotButton.Value
        % Histogramm = app.Histogram
        function s = display_plane(app, ParameterToDisplay, ZPlaneToDisplay, DisplayingAxis, caxisMinVal, caxisMaxVal, ClickOnPlot, Histogramm)
            z_plane = ZPlaneToDisplay;
            % Matlab is column major : image(x,y) <-> array(y,x) 
            X = [0 (size(app.volume.shift(:,:,z_plane), 2) * app.x_pixel_to_um)] ; 
            Y = [0 (size(app.volume.shift(:,:,z_plane), 1) * app.y_pixel_to_um)] ;
            
            switch(ParameterToDisplay)
                case "Width"
                    data = app.volume.width(:,:,z_plane) ;
                case "Shift"
                    data = app.volume.shift(:,:,z_plane) ;
                case "Amplitude"
                    data = app.volume.amplitude(:,:,z_plane) ;                    
                case "Offset"
                    data = app.volume.offset(:,:,z_plane) ;
                case "Index"
                    data = app.volume.raw_data.index(:,:,z_plane) ;
                case "Error"
                    data = app.volume.error(:,:,z_plane) ;
                case "Fitting state"
                    data = app.volume.states(:,:,z_plane) ;
                case "SNR"
                    data = app.volume.snr(:,:,z_plane);
                case "Discrepancy"
                    data = app.volume.discrepancy(:,:,z_plane);
                case "Noise level"
                    data = app.volume.noise_level(:,:,z_plane);
                    
                    % Double Peak fitting
                case "L1_shift"
                    data = app.volume_double_peak.L1.shift(:,:,z_plane) ;
                case "L1_width"
                    data = app.volume_double_peak.L1.width(:,:,z_plane) ;
                case "L1_amplitude"
                    data = app.volume_double_peak.L1.amplitude(:,:,z_plane) ;
                case "L2_shift"
                    data = app.volume_double_peak.L2.shift(:,:,z_plane) ;
                case "L2_width"
                    data = app.volume_double_peak.L2.width(:,:,z_plane) ;
                case "L2_amplitude"
                    data = app.volume_double_peak.L2.amplitude(:,:,z_plane) ;
                case "L12_offset"
                    data = app.volume_double_peak.offset(:,:,z_plane) ;
                case "L12_error"
                    data = app.volume_double_peak.error(:,:,z_plane) ;
                case "L12_states"
                    data = app.volume_double_peak.states(:,:,z_plane) ;
                    
                case "AIC raw single"
                    data = app.AIC.raw.single(:,:,z_plane);
                case "AIC raw double"
                    data = app.AIC.raw.double(:,:,z_plane);
                case "AIC delta (single - double)"
                    data = app.AIC.delta_aic.single(:,:,z_plane);
                case "AIC weight single"
                    data = app.AIC.weight.single(:,:,z_plane);
                case "AIC weight double"
                    data = app.AIC.weight.double(:,:,z_plane);
                case "AIC comparison"
                    data = app.AIC.double_or_single(:,:,z_plane);
                case "BIC raw single"
                    data = app.AIC.bic.double(:,:,z_plane);
                case "BIC delta (single - double)"
                    data = app.AIC.bic.delta(:,:,z_plane) ;
                case "BIC raw double"
                    data = app.AIC.bic.single(:,:,z_plane);
                case "BIC comparison"
                    data = app.AIC.bic.double_or_single(:,:,z_plane);
                case "abs((L1.shift - shift ) - (shift -L2.shift))"
                    data = app.AIC.sanity(:,:,z_plane);
                case "Amplitude ratio"
                    data = app.AIC.ratio(:,:,z_plane);
                case "Delta shift"
                    data = app.AIC.shift_delta(:,:,z_plane);
                case "Ftest"
                    data = app.AIC.Ftest.P(:,:,z_plane);
                case "Filtered"
                    data = app.AIC.filter(:,:,z_plane) ;
                case "AIC double likelyhood"
                    data = app.AIC.double_likelyhood(:,:,z_plane) ;
            end

            %imagesc(DisplayingAxis, X, Y, app.volume.error(:,:,z_plane));
            %colorbar(app.CurrentPlane_2) ;
            %xlim(app.CurrentPlane_2, X) ;
            %ylim(app.CurrentPlane_2, Y) ;

            s = imagesc(DisplayingAxis, X, Y, data);
            colormap(DisplayingAxis, 'jet');
            caxis(DisplayingAxis, [caxisMinVal caxisMaxVal]);
            colorbar(DisplayingAxis) ;
            xlim(DisplayingAxis, [0 X(end)]) ;
            xlabel(DisplayingAxis, "X (in um)");
            ylim(DisplayingAxis, [0 Y(end)]) ;
            ylabel(DisplayingAxis, "Y (in um)")


            if (ClickOnPlot)            
                s.ButtonDownFcn = @(~,e) display_pixel_data(app, e.IntersectionPoint(1),e.IntersectionPoint(2) );
            end
            histogram(Histogramm, data(data > 0));
        end
       
        
        function results = display_pixel_data(app, x_abs, y_abs)
            z = app.ZplaneSpinner.Value;
            %disp([x_abs y_abs])
            x_pixel = round(x_abs / app.x_pixel_to_um) ;
            y_pixel = round(y_abs / app.y_pixel_to_um) ;
            
            %disp([x_pixel y_pixel])
            %disp([(x_pixel*0.25) (y_pixel*0.25)])
            if(x_pixel < 1 || y_pixel < 1  || ... 
                    x_pixel > size(app.volume.shift(:,:,z), 2) || ...
                    y_pixel > size(app.volume.shift(:,:,z), 1) )
                return
            end
            
            app.x_abs = x_pixel * app.x_pixel_to_um ;
            app.y_abs = y_pixel * app.y_pixel_to_um ;            
            
             
            app.PixelRawData.Title.String = "Signal for pixel " + int2str(x_pixel) + "," + int2str(y_pixel) + "," + int2str(z) ...
                + newline + "(" + num2str(app.x_abs) +"um ," + num2str(app.y_abs) +" um)";
            hold(app.CurrentPlane, "on");
            plot(app.CurrentPlane, app.x_abs, app.y_abs, 'k*');
            hold(app.CurrentPlane, "off");
            
            hold(app.CurrentPlane_2, "on");
            plot(app.CurrentPlane_2, app.x_abs, app.y_abs, 'k*');
            hold(app.CurrentPlane_2, "off");
            
            %debug
            %app.volume.shift(y_pixel,x_pixel,z) = 10 ;
            %display_plane(app);
            
            index = app.volume.raw_data.index(y_pixel, x_pixel, z); % Matlab is column major
            
            
            if(index == 0) % We hit a pixel without any raw data (padding pixel)
                app.FittingData.Data = {'Index' index '#' };
                return
            end
            % Preparing display                  
            cla(app.PixelRawData)
            hold(app.PixelRawData, "on");
            
            % Getting the raw data
            
            data_x = app.volume.raw_data.X(:, index, z) ;
            data_y = app.volume.raw_data.Y(:, index, z) ;
            used = logical(app.volume.raw_data.weights(:, index, z)) ; 
            
            
            % Computing the Lorentzian fit
            i_amplitude   = app.volume.amplitude(y_pixel, x_pixel, z);  % Matlab is column major
            i_x_0         = app.volume.shift(y_pixel, x_pixel, z);      % Matlab is column major
            i_gamma       = app.volume.width(y_pixel, x_pixel, z);      % Matlab is column major
            i_offset      = app.volume.offset(y_pixel, x_pixel, z);     % Matlab is column major
            number_points =  size(data_x, 1);
            fitted_Y = zeros(number_points,1);
            for xp = 1:number_points
                fitted_Y(xp) =  i_amplitude * i_gamma^2 / (i_gamma^2 + (data_x(xp) - i_x_0)^2 ) + i_offset; 
            end
            
            % Displaying single fit and raw data
            plot(app.PixelRawData, data_x, fitted_Y, '--', "Color", [0 0 1]) ; % blue
            plot(app.PixelRawData, data_x(used), data_y(used), '.', 'Color', [0 0 0]); % Black
            plot(app.PixelRawData, data_x(not(used)), data_y(not(used)), 'x', 'Color', [0.5 0.5 0.5]); % Grey ? 
            legend_string = ["Fitted curve", "raw Data"] ;
            if(sum(not(used)) > 0)
                legend_string = [legend_string, "raw Data (ignored)"];
            end

            if(app.FixedYaxisSwitch.Value == "On")
                ylim(app.PixelRawData, app.abs_Y_range);
            end
            
            % Computing the double peak fit
            if (app.use_double_peak)
                
                i_amp_1       = app.volume_double_peak.L1.amplitude(y_pixel, x_pixel, z);
                i_x_0_1       = app.volume_double_peak.L1.shift(y_pixel, x_pixel, z);
                i_gsq_1       = app.volume_double_peak.L1.width(y_pixel, x_pixel, z);
                i_amp_2       = app.volume_double_peak.L2.amplitude(y_pixel, x_pixel, z);
                i_x_0_2       = app.volume_double_peak.L2.shift(y_pixel, x_pixel, z);
                i_gsq_2       = app.volume_double_peak.L2.width(y_pixel, x_pixel, z);
                i_offset      = app.volume_double_peak.offset(y_pixel, x_pixel, z);
                
                L_1 = zeros(number_points,1);
                L_2 = zeros(number_points,1);
                double_peak_fitted_Y = zeros(number_points,1);                
                for xp = 1:number_points                    
                    L_1(xp) =  i_amp_1 * i_gsq_1 / (i_gsq_1 + (data_x(xp) - i_x_0_1)^2 ) + i_offset ; 
                    L_2(xp) =  i_amp_2 * i_gsq_2 / (i_gsq_2 + (data_x(xp) - i_x_0_2)^2 ) + i_offset; 
                    double_peak_fitted_Y(xp) =  L_1(xp) + L_2(xp) - i_offset; 
                end
                
                plot(app.PixelRawData, data_x, L_1, '-', 'Color', [1 0 0]); 
                plot(app.PixelRawData, data_x, L_2, '-', 'Color', [0 1 0]); 
                plot(app.PixelRawData, data_x, double_peak_fitted_Y, '-', 'Color', [1 0.2 0]); 
                legend_string = [legend_string,  "L_1", "L_2", "Double Peak"];
                
                if isfield(app.volume_double_peak, "weights") 
                    plot(app.PixelRawData, data_x(app.volume_double_peak.weights(:, index, z) >0), data_y(app.volume_double_peak.weights(:, index, z) >0), 'o', 'Color', [0 0 0]); 
                    legend_string = [legend_string,  "Sample used for double peak"];
                end
            end


            legend(app.PixelRawData, legend_string, "Location",'best');
            hold(app.PixelRawData, "off");

            app.UIFigure.HandleVisibility = 'off';

            % Displaying details of the fit
            
            details = {'Shift' i_x_0 'GHz' ; ...                
                'Amplitude' i_amplitude 'ua' ; ...
                'Width' i_gamma 'ua' ; ...
                'Offset' i_offset 'Volt' ; ...
                'Index' index '#' ; ...
                'Error' app.volume.error(y_pixel,x_pixel,z) '...';
                'State' app.volume.states(y_pixel,x_pixel,z) '...'} ;  % Matlab is column major
            
            if(app.use_double_peak)
                double_details = {'' '' '' ; ...
                    'L1_Shift' i_x_0_1 'GHz' ; ...
                    'L1_amplitude' i_amp_1 'ua' ; ...
                    'L1_width' i_gsq_1 'squarred' ; ...
                    '' '' '' ; ...
                    'L2_Shift' i_x_0_2 'GHz' ; ...
                    'L2_amplitude' i_amp_2 'ua' ; ...
                    'L2_width' i_gsq_2 'squarred' ; ...
                    '' '' '' ; ...
                    'L12_offset' i_offset 'Volt' ; ...
                    'L12_error' app.volume_double_peak.error(y_pixel,x_pixel,z) 'Chi_squarred' ; ...
                    'L12_state' app.volume_double_peak.states(y_pixel,x_pixel,z) 'fit state' };
                details = [details ; double_details] ;    
                
            end
            
            if (app.use_AIC)
                aic_details = {'' '' '' ; ...
                'AIC raw single' app.AIC.raw.single(y_pixel,x_pixel,z) '' ; ...    
                'AIC raw double' app.AIC.raw.double(y_pixel,x_pixel,z) '' ; ...    
                'AIC weight single' app.AIC.weight.single(y_pixel,x_pixel,z) '' ; ...    
                'AIC weight double' app.AIC.weight.double(y_pixel,x_pixel,z) '' ; ...
                'double AIC < single AIC' app.AIC.double_or_single(y_pixel,x_pixel,z) '' ; ...
                
                'BIC raw single' app.AIC.bic.single(y_pixel,x_pixel,z) ''; ...
                'BIC raw double' app.AIC.bic.double(y_pixel,x_pixel,z) ''; ...
                'double BIC < single BIC' app.AIC.bic.double_or_single(y_pixel,x_pixel,z) ''; 
                
                
                };
            details = [details ; aic_details] ;    
            end
            app.FittingData.Data = details;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, experiment_settings, volume_single_peak, volume_double_peak, AIC)
            app.volume = volume_single_peak ;
            
            z_max = size(volume_single_peak.amplitude, 3) ;
            if (z_max == 1) 
                app.ZplaneSpinner.Enable      = 'off' ;
                app.ZplaneSpinner_2.Enable    = 'off' ;
                app.ZplaneSpinner.Value         = 1 ;
                app.ZplaneSpinner_2.Value       = 1;
            else
                app.ZplaneSpinner.Limits = [1 size(volume_single_peak.amplitude, 3)];
                app.ZplaneSpinner_2.Limits = [1 size(volume_single_peak.amplitude, 3)];
            end
            
            app.abs_Y_range = [0  ceil(max(max(volume_single_peak.raw_data.Y)))];
            
            %app.x_pixel_to_um = volume_single_peak.settings.x_pixel_to_um;
            %app.y_pixel_to_um = volume_single_peak.settings.y_pixel_to_um;
            app.x_pixel_to_um = experiment_settings.x_pixel_to_um;
            app.y_pixel_to_um = experiment_settings.y_pixel_to_um;
            
            %app.TotTimeField.Value = volume_single_peak.total_processing_time ; % tic-toc gives the time in seconds
            app.TotTimeField.Value = experiment_settings.total_processing_time ;
            app.SinglePeakTimeField.Value = mean(volume_single_peak.time(:)) ;  

            if(isfield(volume_single_peak, "snr"))
                app.ParametertodisplayDropDown.Items{end+1} = 'SNR' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'SNR' ;
            end

            if(isfield(volume_single_peak, "discrepancy"))
                app.ParametertodisplayDropDown.Items{end+1} = 'Discrepancy' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'Discrepancy' ;
            end

           if(isfield(volume_single_peak, "noise_level"))
                app.ParametertodisplayDropDown.Items{end+1} = 'Noise level' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'Noise level' ;
            end
            
            
            app.use_double_peak = false ;
            app.DoublePeakFittingloadedLamp.Color = [1 0 0];
            if (not(isempty(volume_double_peak))) % if not []
                app.volume_double_peak = volume_double_peak;
                app.use_double_peak = true ;
                app.DoublePeakFittingloadedLamp.Color = [0 1 0];
                
                app.ParametertodisplayDropDown.Items{end+1} = 'L1_shift' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'L1_width' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'L1_amplitude' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'L2_shift' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'L2_width' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'L2_amplitude' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'L12_offset' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'L12_error' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'L12_states' ;
                
                app.ParametertodisplayDropDown_2.Items{end+1} = 'L1_shift' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'L1_width' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'L1_amplitude' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'L2_shift' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'L2_width' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'L2_amplitude' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'L12_offset' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'L12_error' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'L12_states' ;
                
                app.ParametertodisplayDropDown_2.Items{end+1} = 'abs((L1.shift - shift ) - (shift -L2.shift))' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'abs((L1.shift - shift ) - (shift -L2.shift))' ;
                
                app.DoublePeakTimeField.Value = mean(volume_double_peak.time(:));

            end
            
            app.use_AIC = false ;
            if (not(isempty(AIC)))
                app.AIC = AIC ;
                app.use_AIC = true ;
                
                app.ParametertodisplayDropDown.Items{end+1} = 'AIC raw single' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'AIC raw double' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'AIC delta (single - double)' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'AIC weight single' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'AIC weight double' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'AIC comparison' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'BIC raw single' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'BIC raw double' ;
                app.ParametertodisplayDropDown.Items{end+1} = 'BIC comparison' ;
                
                app.ParametertodisplayDropDown_2.Items{end+1} = 'AIC raw single' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'AIC raw double' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'AIC delta (single - double)' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'AIC weight single' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'AIC weight double' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'AIC comparison' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'BIC raw single' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'BIC raw double' ;
                app.ParametertodisplayDropDown_2.Items{end+1} = 'BIC comparison' ;
                

                if(isfield(AIC, "ratio"))
                    app.ParametertodisplayDropDown.Items{end+1} = 'Amplitude ratio' ;
                    app.ParametertodisplayDropDown_2.Items{end+1} = 'Amplitude ratio' ;
                end

                if(isfield(AIC.bic, "delta"))
                    app.ParametertodisplayDropDown.Items{end+1} = 'BIC delta (single - double)' ;
                    app.ParametertodisplayDropDown_2.Items{end+1} = 'BIC delta (single - double)' ;
                end                

               if(isfield(AIC, "shift_delta"))
                    app.ParametertodisplayDropDown.Items{end+1} = 'Delta shift' ;
                    app.ParametertodisplayDropDown_2.Items{end+1} = 'Delta shift' ;
                end

                if(isfield(AIC, "Ftest"))
                    app.ParametertodisplayDropDown.Items{end+1} = 'Filtered' ;
                    app.ParametertodisplayDropDown_2.Items{end+1} = 'Filtered' ;
                end

                if(isfield(AIC, "filter"))
                    app.ParametertodisplayDropDown.Items{end+1} = 'Ftest' ;
                    app.ParametertodisplayDropDown_2.Items{end+1} = 'Ftest' ;
                end
                
                if(isfield(AIC, "double_likelyhood"))
                    app.ParametertodisplayDropDown.Items{end+1} = 'AIC double likelyhood' ;
                    app.ParametertodisplayDropDown_2.Items{end+1} = 'AIC double likelyhood' ;
                end

                
            end

            
            update_display(app, 1);
            update_display(app, 2);
            
            %app.FittingData.Data = {'test',123,'test' ; 'afuq',345,'helpe'} ;
        end

        % Value changed function: ZplaneSpinner
        function ZplaneSpinnerValueChanged(app, event)
            update_display(app, 1);
            
            if(app.LinkZplanesSwitch.Value == "On")
               app.ZplaneSpinner_2.Value = app.ZplaneSpinner.Value; 
               update_display(app, 2);
            end            
        end

        % Value changed function: ParametertodisplayDropDown
        function ParametertodisplayDropDownValueChanged(app, event)
            value = app.ParametertodisplayDropDown.Value;
            update_display(app, 1);
        end

        % Value changed function: MinValEditField
        function MinValEditFieldValueChanged(app, event)
            value = app.MinValEditField.Value;
            if(value > app.MaxValEditField.Value)
                app.MaxValEditField.Value = value + 1;
            end
            update_display(app, 1);
            
        end

        % Value changed function: MaxValEditField
        function MaxValEditFieldValueChanged(app, event)
            value = app.MaxValEditField.Value;
            if (value < app.MinValEditField.Value) 
                app.MinValEditField.Value = value - 1 ;
            end
            update_display(app, 1);
        end

        % Value changed function: ClickonplotButton
        function ClickonplotButtonValueChanged(app, event)
            value = app.ClickonplotButton.Value;
            if (value) 
                app.s_1.ButtonDownFcn = @(~,e) display_pixel_data(app, e.IntersectionPoint(1),e.IntersectionPoint(2) );
            else
                app.s_1.ButtonDownFcn = @(~,e) [];
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key %Move by one pixel per arrow pressed
                case 'rightarrow'
                    display_pixel_data(app, app.x_abs + app.x_pixel_to_um, app.y_abs);
                case 'leftarrow'
                    display_pixel_data(app, app.x_abs - app.x_pixel_to_um, app.y_abs);
                case 'uparrow'
                    display_pixel_data(app, app.x_abs, app.y_abs - app.y_pixel_to_um);
                case 'downarrow'
                    display_pixel_data(app, app.x_abs , app.y_abs + app.y_pixel_to_um);
            end
        end

        % Value changed function: ZplaneSpinner_2
        function ZplaneSpinner_2ValueChanged(app, event)
            update_display(app, 2);
            
            if(app.LinkZplanesSwitch.Value == "On")
               app.ZplaneSpinner.Value = app.ZplaneSpinner_2.Value; 
               update_display(app, 1);
            end  
        end

        % Value changed function: ParametertodisplayDropDown_2
        function ParametertodisplayDropDown_2ValueChanged(app, event)
            value = app.ParametertodisplayDropDown_2.Value;
            update_display(app, 2);
        end

        % Value changed function: MinValEditField_2
        function MinValEditField_2ValueChanged(app, event)
            value = app.MinValEditField_2.Value;
            if(value > app.MaxValEditField_2.Value)
                app.MaxValEditField_2.Value = value + 1;
            end
            update_display(app, 2);
            
        end

        % Value changed function: MaxValEditField_2
        function MaxValEditField_2ValueChanged(app, event)
            value = app.MaxValEditField_2.Value;
            if (value < app.MinValEditField_2.Value) 
                app.MinValEditField_2.Value = value - 1 ;
            end
            update_display(app, 2);
            
        end

        % Value changed function: ClickonplotButton_2
        function ClickonplotButton_2ValueChanged(app, event)
            value = app.ClickonplotButton_2.Value;
            if (value) 
                app.s_2.ButtonDownFcn = @(~,e) display_pixel_data(app, e.IntersectionPoint(1),e.IntersectionPoint(2) );
            else
                app.s_2.ButtonDownFcn = @(~,e) [];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.PixelRawData = uiaxes(app.UIFigure);
            title(app.PixelRawData, 'Imaged plane (value)')
            xlabel(app.PixelRawData, 'X')
            ylabel(app.PixelRawData, 'Y')
            app.FittingData = uitable(app.UIFigure);
            app.FittingData.ColumnName = {'Variable'; 'Value'; 'Unit'};
            app.FittingData.RowName = {};
            app.ImagedplanePanel = uipanel(app.UIFigure);
            app.ImagedplanePanel.Title = 'Imaged plane';
            app.CurrentPlane = uiaxes(app.ImagedplanePanel);
            title(app.CurrentPlane, 'Imaged plane')
            xlabel(app.CurrentPlane, 'X')
            ylabel(app.CurrentPlane, 'Y')
            app.Histogram = uiaxes(app.ImagedplanePanel);
            title(app.Histogram, 'Histogram')
            xlabel(app.Histogram, 'X')
            ylabel(app.Histogram, 'Y')
            app.ZplaneSpinnerLabel = uilabel(app.ImagedplanePanel);
            app.ZplaneSpinnerLabel.Text = 'Z plane';
            app.ZplaneSpinner = uispinner(app.ImagedplanePanel);
            app.ZplaneSpinner.ValueChangedFcn = createCallbackFcn(app, @ZplaneSpinnerValueChanged, true);
            app.ParametertodisplayLabel = uilabel(app.ImagedplanePanel);
            app.ParametertodisplayLabel.Text = {'Parameter '; 'to display'};
            app.ParametertodisplayDropDown = uidropdown(app.ImagedplanePanel);
            app.ParametertodisplayDropDown.Items = {'Shift', 'Width', 'Amplitude', 'Offset', 'Index', 'Error', 'Fitting state'};
            app.ParametertodisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @ParametertodisplayDropDownValueChanged, true);
            app.ParametertodisplayDropDown.Value = 'Shift';
            app.MinvalueEditFieldLabel = uilabel(app.ImagedplanePanel);
            app.MinvalueEditFieldLabel.Text = 'Min value';
            app.MinValEditField = uieditfield(app.ImagedplanePanel, 'numeric');
            app.MinValEditField.ValueChangedFcn = createCallbackFcn(app, @MinValEditFieldValueChanged, true);
            app.MinValEditField.Value = 5.2;
            app.MaxvalueEditFieldLabel = uilabel(app.ImagedplanePanel);
            app.MaxvalueEditFieldLabel.Text = 'Max value';
            app.MaxValEditField = uieditfield(app.ImagedplanePanel, 'numeric');
            app.MaxValEditField.ValueChangedFcn = createCallbackFcn(app, @MaxValEditFieldValueChanged, true);
            app.MaxValEditField.Value = 5.55;
            app.ClickonplotButton = uibutton(app.ImagedplanePanel, 'state');
            app.ClickonplotButton.ValueChangedFcn = createCallbackFcn(app, @ClickonplotButtonValueChanged, true);
            app.ClickonplotButton.Text = 'Click on plot';
            app.ClickonplotButton.Value = true;
            app.Imagedplane2Panel = uipanel(app.UIFigure);
            app.Imagedplane2Panel.Title = 'Imaged plane #2';
            app.CurrentPlane_2 = uiaxes(app.Imagedplane2Panel);
            title(app.CurrentPlane_2, 'Imaged plane')
            xlabel(app.CurrentPlane_2, 'X')
            ylabel(app.CurrentPlane_2, 'Y')
            app.Histogram_2 = uiaxes(app.Imagedplane2Panel);
            title(app.Histogram_2, 'Histogram')
            xlabel(app.Histogram_2, 'X')
            ylabel(app.Histogram_2, 'Y')
            app.ZplaneSpinner_2Label = uilabel(app.Imagedplane2Panel);
            app.ZplaneSpinner_2Label.Text = 'Z plane';
            app.ZplaneSpinner_2 = uispinner(app.Imagedplane2Panel);
            app.ZplaneSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ZplaneSpinner_2ValueChanged, true);
            app.ParametertodisplayDropDown_2Label = uilabel(app.Imagedplane2Panel);
            app.ParametertodisplayDropDown_2Label.Text = {'Parameter'; 'to display'};
            app.ParametertodisplayDropDown_2 = uidropdown(app.Imagedplane2Panel);
            app.ParametertodisplayDropDown_2.Items = {'Shift', 'Width', 'Amplitude', 'Offset', 'Index', 'Error', 'Fitting state'};
            app.ParametertodisplayDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ParametertodisplayDropDown_2ValueChanged, true);
            app.ParametertodisplayDropDown_2.Value = 'Error';
            app.MinvalueEditFieldLabel_2 = uilabel(app.Imagedplane2Panel);
            app.MinvalueEditFieldLabel_2.Text = 'Min value';
            app.MinValEditField_2 = uieditfield(app.Imagedplane2Panel, 'numeric');
            app.MinValEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinValEditField_2ValueChanged, true);
            app.MaxvalueEditFieldLabel_2 = uilabel(app.Imagedplane2Panel);
            app.MaxvalueEditFieldLabel_2.Text = 'Max value';
            app.MaxValEditField_2 = uieditfield(app.Imagedplane2Panel, 'numeric');
            app.MaxValEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxValEditField_2ValueChanged, true);
            app.MaxValEditField_2.Value = 0.5;
            app.ClickonplotButton_2 = uibutton(app.Imagedplane2Panel, 'state');
            app.ClickonplotButton_2.ValueChangedFcn = createCallbackFcn(app, @ClickonplotButton_2ValueChanged, true);
            app.ClickonplotButton_2.Text = 'Click on plot';
            app.ClickonplotButton_2.Value = true;
            app.LinkZplanesSwitchLabel = uilabel(app.UIFigure);
            app.LinkZplanesSwitchLabel.Text = 'Link Z planes';
            app.LinkZplanesSwitch = uiswitch(app.UIFigure, 'toggle');
            app.LinkZplanesSwitch.Value = 'On';
            app.DoublePeakFittingloadedLampLabel = uilabel(app.UIFigure);
            app.DoublePeakFittingloadedLampLabel.Text = {'Double Peak '; 'Fitting loaded'};
            app.DoublePeakFittingloadedLamp = uilamp(app.UIFigure);
            app.TotalprocessingtimesLabel = uilabel(app.UIFigure);
            app.TotalprocessingtimesLabel.Text = 'Total processing time (s)';
            app.TotTimeField = uieditfield(app.UIFigure, 'numeric');
            app.TotTimeField.Editable = 'off';
            app.AveragesinglepeakfittingtimemsplaneEditFieldLabel = uilabel(app.UIFigure);
            app.AveragesinglepeakfittingtimemsplaneEditFieldLabel.Text = 'Average single peak fitting time (ms/plane)';
            app.SinglePeakTimeField = uieditfield(app.UIFigure, 'numeric');
            app.SinglePeakTimeField.Editable = 'off';
            app.AveragedoublepeakfittingtimemsplaneEditFieldLabel = uilabel(app.UIFigure);
            app.AveragedoublepeakfittingtimemsplaneEditFieldLabel.Text = 'Average double peak fitting time (ms/plane)';
            app.DoublePeakTimeField = uieditfield(app.UIFigure, 'numeric');
            app.DoublePeakTimeField.Editable = 'off';
            app.v031Label = uilabel(app.UIFigure);
            app.v031Label.Text = 'v0.3.1';
            app.FixedYaxisSwitchLabel = uilabel(app.UIFigure);
            app.FixedYaxisSwitchLabel.Text = 'Fixed Y-axis';
            app.FixedYaxisSwitch = uiswitch(app.UIFigure, 'toggle');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = volume_display(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end